classdef BehaviorAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BehaviorAnalysisLabel        matlab.ui.control.Label
        BehaviorTestsPanel           matlab.ui.container.Panel
        MorePlotsRawDataButton       matlab.ui.control.Button
        ButtonGroup_2                matlab.ui.container.ButtonGroup
        PlotMeanSDButton             matlab.ui.control.RadioButton
        PlotRawDataButton            matlab.ui.control.RadioButton
        resultsButton                matlab.ui.control.Button
        typetestDropDown             matlab.ui.control.DropDown
        LoadConvertedDataButton      matlab.ui.control.Button
        ImportDatatoconvertButton    matlab.ui.control.Button
        InfraRedPanel                matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        DropDown_2                   matlab.ui.control.DropDown
        ButtonGroup                  matlab.ui.container.ButtonGroup
        spesificfileButton           matlab.ui.control.RadioButton
        allfilesButton               matlab.ui.control.RadioButton
        LoadDataButton               matlab.ui.control.Button
        baselinedaysEditField        matlab.ui.control.NumericEditField
        baselinedaysLabel            matlab.ui.control.Label
        baselinedaysEditField_2      matlab.ui.control.NumericEditField
        DropDown                     matlab.ui.control.DropDown
        MorePlotsButton              matlab.ui.control.Button
        PlotActivityButton           matlab.ui.control.Button
        lightcyclecloset3DropDown    matlab.ui.control.DropDown
        lightcyclecloset2DropDown_2  matlab.ui.control.DropDown
        choosepathPanel              matlab.ui.container.Panel
        SaveplotsButton              matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        BrowseButton                 matlab.ui.control.Button
        clearButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        path
        amount_of_files
        DATA % the rau data
        names
        Ta%type of light cycle for closet a
        Tb%type of light cycle for closet b
        numHours % number of 48-h periods (number of raws in actogram) or 24-h periods (number of raws in actogram)
        begin%nuber days of baseline in closet a&b
        colores

        BehaviorData
        chosenTest
        RatioData
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.begin=[0,0];
            app.colores=['y','y'];
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,temppath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            if isempty(temppath) || isempty(file)
                msgbox('data was not downloaded',"Error");
            else
                if app.allfilesButton.Value %to check if i want all the files in the path
                    app.names=getFileList(temppath,'counts_',0,'anywhere');  % get names of data files
                    data=[];
                    for i=1:length(app.names)
                        data=[data;readtimetable(num2str(app.names{i}),'ReadVariableNames',0)];%change to timetable
                    end
                    app.DATA=timetable2table(data);
                else %i want just one file
                    filepath=fullfile(temppath,file);%path for the file i want
                    singlef=readtimetable(filepath);%
                    app.DATA=timetable2table(singlef);%change to timetable
                end

                msgbox('data was downloaded',"Success");

            end
        end

        % Value changed function: lightcyclecloset2DropDown_2
        function lightcyclecloset2DropDown_2ValueChanged(app, event)
            value = app.lightcyclecloset2DropDown_2.Value;
            app.Ta=value;
            if app.Ta ~= "T24"
                app.baselinedaysEditField.Visible="on";
                app.baselinedaysLabel.Visible="on";
                app.DropDown_2.Visible="on";
                app.Lamp.Visible='on';
            end
        end

        % Value changed function: lightcyclecloset3DropDown
        function lightcyclecloset3DropDownValueChanged(app, event)
            value = app.lightcyclecloset3DropDown.Value;
            app.Tb=value;
            if app.Tb ~= "T24"
                app.baselinedaysEditField_2.Visible="on";
            end
        end

        % Button pushed function: PlotActivityButton
        function PlotActivityButtonPushed(app, event)
            switch app.numHours
                case "24 hours plot"
                    PlotTaVSTbn24(app.DATA,app.Ta,app.Tb,app.begin,app.colores);
                case "48 hours plot"
                    PlotTaVSTbn48(app.DATA,app.Ta,app.Tb,app.begin,app.colores);
            end
            app.SaveplotsButton.Visible="on";
        end

        % Button pushed function: SaveplotsButton
        function SaveplotsButtonPushed(app, event)
            numFigs=2;
            for i=1:numFigs
                name=figure(i).name;
                exportgraphics(figure(i), 'output.pdf', 'Append', true);
            end

            FolderName = app.path;   % Your destination folder
            FigList = findobj(allchild(0), 'flat', 'Type', 'figure');
            for iFig = 1:length(FigList)
                FigHandle = FigList(iFig);
                FigName   = num2str(get(FigHandle, 'Number'));
                set(0, 'CurrentFigure', FigHandle);
                savefig(fullfile(FolderName, [FigName '.fig']));
            end
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
            clear
            clc
        end

        % Button pushed function: ImportDatatoconvertButton
        function ImportDatatoconvertButtonPushed(app, event)
            [file,mypath] = uigetfile('*.csv',"select csv file",app.path);  %open the dialog box
            if isempty(mypath) || isempty(file)
                msgbox('data was not downloaded',"Error");
            else
                app.BehaviorData = Converter(mypath,file);
                msgbox('data was downloaded',"Success");
            end
        end

        % Value changed function: typetestDropDown
        function typetestDropDownValueChanged(app, event)
            value = app.typetestDropDown.Value;
            app.chosenTest=value;
        end

        % Button pushed function: resultsButton
        function resultsButtonPushed(app, event)
            if app.PlotMeanSDButton.Value%ploting mean data
                switch app.chosenTest
                    case 'OFT'
                        plotGroupComparison(app.RatioData,'OF',{'% time in open arm','% distance in open arm'})
                    case 'EPM'
                        plotGroupComparison(app.RatioData,'EPM',{'% time in open arm','% distance in open arm'})
                    case 'SPT'
                        plotGroupComparison(app.RatioData,'SP',{'% preference','% preference'})
                        %                     case 'SPT baseline'
                        %                         plotGroupComparison(app.rawData,'SP','Preference')
                    case 'FST'
                        plotGroupComparison(app.RatioData,'FS',{'% immobility time','% immobility Frequency'})
                    case 'All tests'
                        yaxelabel.OF={'% time in open arm','% distance in open arm'};
                        yaxelabel.EPM={'% time in open arm','% distance in open arm'};
                        yaxelabel.SP={'% preference','% preference'};
                        yaxelabel.FS={'% immobility time','% immobility Frequency'};
                        GenAll_Plots(app.RatioData,yaxelabel);
                end
            else
                switch app.chosenTest
                    case 'OFT'
                        plotGroupComparison_rawData(app.BehaviorData,'OF',{'Mean time in zone (s)','Mean distance in zone (cm)'})
                    case 'EPM'
                        plotGroupComparison_rawData(app.BehaviorData,'EPM',{'Mean time in zone (s)','Mean distance in zone (cm)'})
                    case 'SPT'
                        plotGroupComparison_rawData(app.BehaviorData,'SP',{'Mean consumption(g)','Mean consumption(g)'})
                        %                     case 'SPT baseline'
                        %                         plotGroupComparison_rawData(app.newdata,'SP','Preference')
                    case 'FST'
                        plotGroupComparison_rawData(app.BehaviorData,'FS',{'Mean activity time (s)','Mean activity Frequency'})
                    case 'All tests'
                        yaxelabel.OF={'Mean time in zone (s)','Mean distance in zone (cm)'};
                        yaxelabel.EPM={'Mean time in zone (s)','Mean distance in zone (cm)'};
                        yaxelabel.SP={'Mean consumption(g)','Mean consumption(g)'};
                        yaxelabel.FS={'Mean activity time (s)','Mean activity Frequency'};
                        GenAllPlots_rawData(app.BehaviorData,yaxelabel);
                end


            end
            app.SaveplotsButton.Visible="on";
        end

        % Button pushed function: MorePlotsButton
        function MorePlotsButtonPushed(app, event)
            more_plots(app.DATA,app.Ta,app.Tb);
        end

        % Button pushed function: LoadConvertedDataButton
        function LoadConvertedDataButtonPushed(app, event)
            [file,app.path] = uigetfile('*.mat',"select mat file",app.path);  %open the dialog box
            if isempty(app.path) || isempty(file)
                msgbox('data was not downloaded',"Error");
            else
                load(fullfile(app.path, file),'newdata');
                app.BehaviorData=newdata;
                msgbox('data was downloaded',"Success");
                app.RatioData=analyzingData(app.BehaviorData);

            end

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.path = uigetdir();  %open the dialog box
            app.EditField.Value=app.path;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.numHours=value;
        end

        % Value changed function: baselinedaysEditField
        function baselinedaysEditFieldValueChanged(app, event)
            value = app.baselinedaysEditField.Value;
            app.begin(1)=value;
        end

        % Value changed function: baselinedaysEditField_2
        function baselinedaysEditField_2ValueChanged(app, event)
            value = app.baselinedaysEditField_2.Value;
            app.begin(2)=value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %             selectedButton = app.ButtonGroup.SelectedObject;
            %             app.amount_of_files=selectedButton;
            %             if app.amount_of_files=="all files"
            %                 msgbox('data was downloaded',"Success");
            %             else
            %                 msgbox('data was not downloaded',"Error");
            %             end
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            if strcmp(value,'Blue')
                app.Lamp.Color='c';
                app.colores(2)='c';
            else
                app.Lamp.Color='y';
                app.colores(2)='y';
            end
        end

        % Button pushed function: MorePlotsRawDataButton
        function MorePlotsRawDataButtonPushed(app, event)
            if app.PlotMeanSDButton.Value%ploting mean data
                switch app.chosenTest
                    case 'OFT'
                        MyMorePlots(app.RatioData,'OF',{'% time in open arm','% distance in open arm'})
                    case 'EPM'
                        MyMorePlots(app.RatioData,'EPM',{'% time in open arm','% distance in open arm'})
                    case 'SPT'
                        MyMorePlots(app.RatioData,'SP',{'% preference','% preference'})
                        %                     case 'SPT baseline'
                        %                         plotGroupComparison(app.rawData,'SP','Preference')
                    case 'FST'
                        MyMorePlots(app.RatioData,'FS',{'% immobility time','% immobility Frequency'})
                    case 'All tests'
                        yaxelabel.OF={'% time in open arm','% distance in open arm'};
                        yaxelabel.EPM={'% time in open arm','% distance in open arm'};
                        yaxelabel.SP={'% preference','% preference'};
                        yaxelabel.FS={'% immobility time','% immobility Frequency'};
                        GenAll_Plots(app.rawData,yaxelabel);
                end
            else
                switch app.chosenTest
                    case 'OFT'
                        MyMorePlots_rawData(app.BehaviorData,'OF',{'Mean time in zone (s)','Mean distance in zone (cm)'})
                    case 'EPM'
                        MyMorePlots_rawData(app.BehaviorData,'EPM',{'Mean time in zone (s)','Mean distance in zone (cm)'})
                    case 'SPT'
                        MyMorePlots_rawData(app.BehaviorData,'SP',{'Mean consumption(g)','Mean consumption(g)'})
                        %                     case 'SPT baseline'
                        %                         plotGroupComparison_rawData(app.newdata,'SP','Preference')
                    case 'FST'
                        MyMorePlots_rawData(app.BehaviorData,'FS',{'Mean activity time (s)','Mean activity Frequency'})
                    case 'All tests'
                        yaxelabel.OF={'Mean time in zone (s)','Mean distance in zone (cm)'};
                        yaxelabel.EPM={'Mean time in zone (s)','Mean distance in zone (cm)'};
                        yaxelabel.SP={'Mean consumption(g)','Mean consumption(g)'};
                        yaxelabel.FS={'Mean activity time (s)','Mean activity Frequency'};
                        GenAllPlots_rawData(app.BehaviorData,yaxelabel);
                end


            end
            app.SaveplotsButton.Visible="on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.choosepathPanel = uipanel(app.UIFigure);
            app.choosepathPanel.Title = 'choose path';
            app.BrowseButton = uibutton(app.choosepathPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Icon = fullfile(pathToMLAPP, 'icon_browse.png');
            app.BrowseButton.Text = 'Browse';
            app.EditField = uieditfield(app.choosepathPanel, 'text');
            app.SaveplotsButton = uibutton(app.choosepathPanel, 'push');
            app.SaveplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveplotsButtonPushed, true);
            app.SaveplotsButton.Icon = fullfile(pathToMLAPP, 'icon_save.png');
            app.SaveplotsButton.Visible = 'off';
            app.SaveplotsButton.Text = 'Save plots';
            app.InfraRedPanel = uipanel(app.UIFigure);
            app.InfraRedPanel.Title = 'InfraRed';
            app.lightcyclecloset2DropDown_2 = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset2DropDown_2.Items = {'choose light cycle closet 2', 'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset2DropDown_2.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset2DropDown_2ValueChanged, true);
            app.lightcyclecloset2DropDown_2.Value = 'choose light cycle closet 2';
            app.lightcyclecloset3DropDown = uidropdown(app.InfraRedPanel);
            app.lightcyclecloset3DropDown.Items = {'choose light cycle closet 3', 'T7', 'T6', 'T24', 'CT13', 'CT16', 'CT18', 'CT20'};
            app.lightcyclecloset3DropDown.ValueChangedFcn = createCallbackFcn(app, @lightcyclecloset3DropDownValueChanged, true);
            app.lightcyclecloset3DropDown.Value = 'choose light cycle closet 3';
            app.PlotActivityButton = uibutton(app.InfraRedPanel, 'push');
            app.PlotActivityButton.ButtonPushedFcn = createCallbackFcn(app, @PlotActivityButtonPushed, true);
            app.PlotActivityButton.Text = 'Plot Activity';
            app.MorePlotsButton = uibutton(app.InfraRedPanel, 'push');
            app.MorePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @MorePlotsButtonPushed, true);
            app.MorePlotsButton.Text = 'More Plots';
            app.DropDown = uidropdown(app.InfraRedPanel);
            app.DropDown.Items = {'choose type hour', '24 hours plot', '48 hours plot'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'choose type hour';
            app.baselinedaysEditField_2 = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField_2.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditField_2ValueChanged, true);
            app.baselinedaysEditField_2.Visible = 'off';
            app.baselinedaysLabel = uilabel(app.InfraRedPanel);
            app.baselinedaysLabel.Visible = 'off';
            app.baselinedaysLabel.Text = {'baseline '; 'days'};
            app.baselinedaysEditField = uieditfield(app.InfraRedPanel, 'numeric');
            app.baselinedaysEditField.ValueChangedFcn = createCallbackFcn(app, @baselinedaysEditFieldValueChanged, true);
            app.baselinedaysEditField.Visible = 'off';
            app.LoadDataButton = uibutton(app.InfraRedPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Icon = fullfile(pathToMLAPP, 'icon_load_15.jpg');
            app.LoadDataButton.Text = 'Load Data';
            app.ButtonGroup = uibuttongroup(app.InfraRedPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.allfilesButton = uiradiobutton(app.ButtonGroup);
            app.allfilesButton.Text = 'all files';
            app.allfilesButton.Value = true;
            app.spesificfileButton = uiradiobutton(app.ButtonGroup);
            app.spesificfileButton.Text = 'spesific file';
            app.DropDown_2 = uidropdown(app.InfraRedPanel);
            app.DropDown_2.Items = {'colors', 'Blue', 'Yellow'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_2.Value = 'colors';
            app.Lamp = uilamp(app.InfraRedPanel);
            app.Lamp.Visible = 'off';
            app.BehaviorTestsPanel = uipanel(app.UIFigure);
            app.BehaviorTestsPanel.Title = 'BehaviorTests';
            app.ImportDatatoconvertButton = uibutton(app.BehaviorTestsPanel, 'push');
            app.ImportDatatoconvertButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatatoconvertButtonPushed, true);
            app.ImportDatatoconvertButton.Icon = fullfile(pathToMLAPP, 'icon_convert.png');
            app.ImportDatatoconvertButton.Text = 'Import Data to convert';
            app.LoadConvertedDataButton = uibutton(app.BehaviorTestsPanel, 'push');
            app.LoadConvertedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConvertedDataButtonPushed, true);
            app.LoadConvertedDataButton.Icon = fullfile(pathToMLAPP, 'icon_load_15.jpg');
            app.LoadConvertedDataButton.Text = 'Load Converted Data';
            app.typetestDropDown = uidropdown(app.BehaviorTestsPanel);
            app.typetestDropDown.Items = {'choose test type ', 'OFT', 'EPM', 'SPT', 'SPT baseline', 'FST', 'All tests'};
            app.typetestDropDown.ValueChangedFcn = createCallbackFcn(app, @typetestDropDownValueChanged, true);
            app.typetestDropDown.Value = 'choose test type ';
            app.resultsButton = uibutton(app.BehaviorTestsPanel, 'push');
            app.resultsButton.ButtonPushedFcn = createCallbackFcn(app, @resultsButtonPushed, true);
            app.resultsButton.Text = 'results';
            app.ButtonGroup_2 = uibuttongroup(app.BehaviorTestsPanel);
            app.PlotRawDataButton = uiradiobutton(app.ButtonGroup_2);
            app.PlotRawDataButton.Text = 'Plot Raw Data';
            app.PlotRawDataButton.Value = true;
            app.PlotMeanSDButton = uiradiobutton(app.ButtonGroup_2);
            app.PlotMeanSDButton.Text = 'Plot Mean ± S.D. ';
            app.MorePlotsRawDataButton = uibutton(app.BehaviorTestsPanel, 'push');
            app.MorePlotsRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @MorePlotsRawDataButtonPushed, true);
            app.MorePlotsRawDataButton.Text = 'More Plots Raw Data';
            app.BehaviorAnalysisLabel = uilabel(app.UIFigure);
            app.BehaviorAnalysisLabel.Text = 'BehaviorAnalysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BehaviorAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end